classdef PIASpectra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        ExportationLabel         matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        LoadCreateError          matlab.ui.control.Label
        LoadTDMSButton           matlab.ui.control.Button
        CenterPanel              matlab.ui.container.Panel
        NextButton               matlab.ui.control.Button
        PrevButton               matlab.ui.control.Button
        SpecPlot                 matlab.ui.control.UIAxes
        NormKinPlot              matlab.ui.control.UIAxes
        KinPlot                  matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        ExportSpectraDataButton  matlab.ui.control.Button
        ExportFilteredCSVButton  matlab.ui.control.Button
        KineticLevelDisplay      matlab.ui.control.Label
        BackgroundLvlDisplay     matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        AddButton                matlab.ui.control.Button
        Description              matlab.ui.control.Label
        PlotDetailsLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)
        Path % Property that holds the path of the folder we are looking at
        rst % Property that holds a boolean check of if we are refreshing already loaded data
        waves % Store list of wavelengths from header names
        LinDataSort % Store the sorted LinData table
        LogDataSort % Store the sorted LogData table
        timeRG % Store the TimeRG table
        dataRG % Store the DataRG table as all the y data values
        Traces % Store the number of traces
        BackgroundLevel % Store BackgroundLvl table
        changeAxes % Store whether or not we are keeping Y-axis fixed
        KinInd % Store index of which file we are looking at
        ShadeArea % Array to store 2 vectors for each ROI for starting and end pos.
        TimeWidths % Store time absolute widths for each ROI
        TimeFields % Store time centres for each ROI

        SpecYRange % Store the Y-range of the spectra axes
        FixedYRange % Store the Y-range of kinetic axes
        SpecDataPlot % Store the spec plot data of the specified index
        KinDataPlot % Store the kin plot data of the specified index
        DataPlot % Hold the kinetics plot object

        XPointsROI % Store ROI objects in cell array
        Plot_Wavelengths % Sorted string2double wavelengths nums
        
        TimeLinSpecArray % Property to hold sorted table2array time values (1st column)
        SortedYLinArray % Property to hold sorted table2array y values for each set (2:end)
        KinDataArray % Property to hold sorted table2array kin data values
    end

    methods (Access = private)

        function buildSpecData(app)
            % Set default relative width to 10%
            SpecWidth = 0.10;

            % Preallocate for faster speeds
            app.SpecDataPlot = [];
            r = size(app.LinDataSort(:,2:end),2);
            c = size(app.TimeFields,1);
            app.SpecDataPlot = nan(r,c);

            if isempty(get(app.KinPlot, 'Children')) == 1

                for Spec = 1:1:size(app.TimeFields)
                    CenterTime = app.TimeFields(Spec);
                    StartTime = CenterTime *(1 - SpecWidth/2);
                    EndTime = CenterTime * (1 + SpecWidth/2);

                    StartInd = find(StartTime > app.TimeLinSpecArray,1,'last') + 1;
                    EndInd = find(EndTime < app.TimeLinSpecArray,1,'first') - 1;

                    app.SpecDataPlot(:,Spec) = mean(app.SortedYLinArray(StartInd:EndInd,:),1);
                    app.ShadeArea(Spec,1:2) = [StartTime EndTime];
                    app.TimeWidths(Spec,1) = EndTime - StartTime;
                end

                % Start of plotting in axes KinPlot
                grid(app.KinPlot, 'off')
                updateData(app);
                YCheck(app);
                drawAreas(app);
                plotKinData(app);
            else
                Tester(app);
                updateData(app);
                plotKinData(app);
                YCheck(app);

            end
            RGPlot(app);
        end

        function updateData(app)
            % Time variables (x-component stay the same so don't update)
            % Pull most recent data and populate properties
            Kindata = app.dataRG(:,2:end);
            app.KinDataPlot = Kindata(:,app.KinInd);
            app.KinDataArray = table2array(app.KinDataPlot);
        end

        function YCheck(app)
            % Check if Y-axis is to be kept constant
            %             if app.KeepKinPlotaxisfixedCheckBox.Value == 0
            axis(app.KinPlot,[1E-7 max(app.TimeLinSpecArray) min(app.KinDataArray) max(app.KinDataArray)]);
            app.FixedYRange = [min(app.KinDataArray) max(app.KinDataArray)];
            %             else
            %                 axis(app.KinPlot,[1E-7 max(app.TimeLinSpecArray) app.FixedYRange]);
            %                 app.FixedYRange = app.KinPlot.YLim;
            %             end

            % Alter accordingly if in LogY scale
            %             if app.ButtonGroup.SelectedObject == app.LogYScaleButton
            %                 app.FixedYRange(1) = app.KinPlot.YTick(1)*(0.10);
            %             end
        end

        function drawAreas(app)
            % Delete any previous data in cell array and preallocate
            sz = size(app.TimeFields,1);
            app.XPointsROI{1,sz} = [];

            % Set color function with expected num of colors
            RGcolor = turbo(7);
            RGcolor = flipud(RGcolor);

            % Check if in LogY Scale to change height to non negative
            %             if (app.ButtonGroup.SelectedObject == app.LogYScaleButton)
            %                 height = (app.FixedYRange(2)-app.FixedYRange(1))*(.80);
            %             else
            height = (app.FixedYRange(2)-app.FixedYRange(1))*(1.50);
            %             end

            % Set starting x and width for drawing area of each ROI
            x = app.KinPlot.XLim(1);
            w = app.KinPlot.XLim(2) - app.KinPlot.XLim(1);

            for i = 1:1:sz
                % Create our editable areas (as rectangle objects)
                app.XPointsROI{i} = images.roi.Rectangle(app.KinPlot, "Position", [app.ShadeArea(i,1) app.FixedYRange(1)*(1.1) app.TimeWidths(i) height], 'Color', RGcolor(i,:), 'DrawingArea',[x app.FixedYRange(1)-0.005 w height] ,'Tag', num2str(i), 'LineWidth', 1); % 'MarkerSize', 4);

                % Add callbacks for when point if moved
                addlistener(app.XPointsROI{i}, "MovingROI", @(src,event)ROIChangedCallback(app,src,event));
            end
        end

        function updateAreas(app)
            % Only update new height parameters between data
            % Check if in LogY Scale to change height to non negative
%             if (app.ButtonGroup.SelectedObject == app.LogYScaleButton)
%                 height = (app.FixedYRange(2)-app.FixedYRange(1))*(.80);
%             else
                height = (app.FixedYRange(2)-app.FixedYRange(1))*(1.40);
%             end

            for i = 1:1:size(app.TimeFields)
                app.XPointsROI{i}.Position = [app.ShadeArea(i,1) app.FixedYRange(1)*(1.1) app.TimeWidths(i) height];
            end
        end

        function plotKinData(app)
            % Plot the data on axes figure
            delete(app.DataPlot)
            hold(app.KinPlot, 'on');
            app.DataPlot = line(app.KinPlot, app.TimeLinSpecArray, app.KinDataArray, 'Color','b');
            hold(app.KinPlot, 'off');
        end

        function Tester(app,~)
            % Calculate new x data and therefore update spec data
            for i = 1:1:size(app.TimeFields,1)
                CenterTime = app.TimeFields(i);

                StartTime = CenterTime *(1 - (app.TimeWidths(i)/CenterTime)/2);
                EndTime = CenterTime * (1 + (app.TimeWidths(i)/CenterTime)/2);

                StartInd = find(StartTime > app.TimeLinSpecArray,1,'last') + 1;
                EndInd = find(EndTime < app.TimeLinSpecArray,1,'first') - 1;

                app.SpecDataPlot(:,i) = mean(app.SortedYLinArray(StartInd:EndInd,:),1);

                if nargin == 1
                    app.ShadeArea(i,1:2) = [StartTime EndTime];
                end
            end
        end


        function ROIChangedCallback(app, ~, event)
            % Deal with callback; handle mouse input
            % Variables carrying all the new data
            eventSource = get(event.Source);
            ind = str2double(eventSource.Tag);

            % Preallocate for faster speeds
            app.SpecDataPlot = [];
            r = size(app.LinDataSort(:,2:end),2);
            c = size(app.TimeFields, 2);
            app.SpecDataPlot = nan(r,c);

            % Conditional variables for different listeners (reshape vs translate)
            newwidth = eventSource.Position(3);
            oldwidth = app.TimeWidths(ind);
            currentCenter = app.TimeFields(ind);

            % RESHAPE ROI
            if ~(oldwidth == newwidth)

                % Then we are manually reshaping left side so adjust the right
                if ~(eventSource.Position(1) == app.ShadeArea(ind,1))
                    app.TimeWidths(ind) = 2*(currentCenter - eventSource.Position(1));
                    app.XPointsROI{ind}.Position(3) = app.TimeWidths(ind);

                    % Then we are manually reshaping right side so do nothing
                else
                    app.XPointsROI{ind}.Position(3) = app.TimeWidths(ind);
                end

                % TRANSLATE ROI
            else
                % We only know the x position of left most line.
                % We need to use this knowldge to calculate new center but keeping same relative width

                newCenter = eventSource.Position(1)/(1 - (app.UITable.Data{ind,2}/200));
                app.XPointsROI{ind}.Position(3) = newCenter*(app.UITable.Data{ind,2}/100);
                app.TimeWidths(ind) = app.XPointsROI{ind}.Position(3);
                app.TimeFields(ind) = newCenter;
            end
            Tester(app);
            RGPlot(app);
        end

        function RGPlot(app)
            %             c = tic();
            % Sort the plotted wavelengths
            app.Plot_Wavelengths = sort(str2double(app.waves));

            % Clear plot to ensure no overlap
            cla(app.SpecPlot, 'reset');

            % Set color function with expected num of colors
            RGcolor = turbo(7);
            RGcolor = flipud(RGcolor);

            % Keep X scale Logarithmic
%             app.KinPlot.XScale = 'log';

            % Plot reference Y = 0 line if Y scale is linear.
            if strcmp(app.KinPlot.YScale,'linear') == 1
                % Make sure to delete previous reflines since we don't
                % clear, then send refline to the bottom for easier access to ROI
                %                 if ishandle(KinRef)
                %                     delete(KinRef)
                %                 end

                KinRef = refline(app.KinPlot, 0, 0);
                KinRef.Color = [0 0 0];
                uistack(KinRef, "bottom");
            end

            % Plot Spectral data and check if there are more than one tdms file opened. Change code accordingly
            [m,~] = size(app.SpecDataPlot);
            if m > 1
                plotSpecData(app,RGcolor);

                % Check if Y-axis is fixed and change SpecYRange accordingly if so
%                 if app.changeAxes
                    app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
%                 else
%                     ...
%                 end

                axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) app.SpecYRange]);

            else
                plotSpecData(app,RGcolor);
%                 if app.changeAxes
                    if app.Plot_Wavelengths(1) == max(app.Plot_Wavelengths) % If there is only one wavelength?
                        axis(app.SpecPlot, [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1 min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))]);
                        app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                    else
                        axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))]);
                        app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                    end
%                 else
%                     if app.Plot_Wavelengths(1) == max(app.Plot_Wavelengths)
%                         axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) app.SpecYRange]);
%                     else
%                         axis(app.SpecPlot, [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1 app.SpecYRange]);
%                     end
%                 end
            end

            % Display new UITable values
            data(:,1) = arrayfun(@(x) sprintf('%.2e\n',x),app.TimeFields,'un',0);
            data(:,2) = num2cell(round((app.TimeWidths./app.TimeFields).*100));

            app.UITable.Data = data;

            % Display the proper wavelengths/position text depending on TAS or TAM data
            app.KineticLevelDisplay.Text = "Displaying kinetic at " + app.Plot_Wavelengths(app.KinInd) + " nm";
            app.BackgroundLvlDisplay.Text = "Background level was " + table2array(app.BackgroundLevel(1,app.KinInd)) + " V";

            % Plots lose labels on clear so reset each time
            app.KinPlot.Title.String = "Kinetics";
            app.KinPlot.XLabel.String = "Time (s)";
            app.KinPlot.YLabel.String = "ΔOD or %Abs";
            app.SpecPlot.Title.String = "Spectra";
            app.SpecPlot.XLabel.String = "Wavelength (nm)";
            app.SpecPlot.YLabel.String = "ΔOD or %Abs";
            %             d = toc(c)
        end

        function plotSpecData(app, RGcolor)
            % Plot new respective wavelengths
            for i = 1:1:size(app.SpecDataPlot,2)
                line(app.SpecPlot, app.Plot_Wavelengths, app.SpecDataPlot(:,i), 'Color', RGcolor(i,:));
                hold(app.SpecPlot, 'on');
            end
            SpecRef = refline(app.SpecPlot,0,0);
            SpecRef.Color = [0 0 0];
            hold(app.SpecPlot, 'off');
        end

        
        function clearProperties(app)
            % Set all properties back to default values
            app.waves = {};
            app.positions = {};
            app.BackgroundLevel = {};
            app.LinDataSort = {};
            app.LogDataSort = {};

            app.dataRG = {};
            app.timeRG = {};

            app.changeAxes = true;
            app.FixedYRange = [];
            app.SpecYRange = [];
            app.KinDataPlot = [];
            app.SpecDataPlot = [];
            delete(app.DataPlot);
            app.ShadeArea = [];
            app.Traces = [];
            app.XPointsROI = {};
            app.Path = "";
            app.LinearData = {};
            app.LogarithmData = {};
            app.time = [];
            app.LogTimeArr = [];
            app.KinInd = 1;
            app.Plot_Wavelengths = {};
            app.TimeLinSpecArray = []; 
            app.SortedYLinArray = [];

            app.LinearYscaleButton.Value = true;
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.KeepSpecPlotYaxisfixedCheckBox.Value = 0;
            app.TimeFields = [1E-3; 1E-2; 1E-1; 1E0; 1E1];
            app.TimeWidths = [0.1; 0.1; 0.1; 0.1; 0.1];

            cla(app.KinPlot, "reset")
            cla(app.SpecPlot, "reset")
        end

        function addRow(app)
            % Determine end magnitude by splitting exponent of last element
            last = (app.UITable.Data(end, 1));
            spt = split (last,'e');
            num2 = spt(2);
            mag0 = str2double(num2);
            mag = mag0 + 1;
            mag = 10^mag;

            % Add new field values, then clear and update app
            app.TimeFields(end + 1) = 1.00*mag;
            app.TimeWidths(end + 1) = 0.1*app.TimeFields(end);

            % More efficient, it doesn't redraw everything, only deletes rectangles now
            Tester(app);
            rec = findobj(app.KinPlot,'Type','images.roi.Rectangle');
            delete(rec);
            YCheck(app);
            drawAreas(app);
            RGPlot(app);
        end

        function deleteRow(app, indices)
            % Find index of row
            ind = indices(1);

            % Clear variables connected to row
            app.UITable.Data(ind,:) = [];
            app.TimeFields(ind) = [];
            app.TimeWidths(ind) = [];
            app.XPointsROI{ind} = [];
            app.SpecDataPlot(:,ind) = [];
            app.ShadeArea(ind,:) = [];

            Tester(app);
            rec = findobj(app.KinPlot,'Type','images.roi.Rectangle');
            delete(rec);
            YCheck(app);
            drawAreas(app);
            RGPlot(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Determine where your m-file's folder is.
            folder = fileparts(which(mfilename));

            % Add that folder plus all subfolders to the path.
            addpath(genpath(folder));

            % Hide unused GUI objects
            app.CenterPanel.Visible = false;
            app.RightPanel.Visible = false;

            % Set default boolean startup values
            app.rst = 0;
            app.TimeFields = [1E-3; 1E-2; 1E-1; 1E0; 1E1];
        end

        % Button pushed function: LoadTDMSButton
        function LoadTDMSButtonPushed(app, event)
            % Check if we start from startup fcn or reset fcn and set path accordingly
            if app.rst == 0
                % Disable button until data is loaded
                app.LoadTDMSButton.Enable = 'off';
                try
                    app.LoadCreateError.Text = "Loading data...";
                    FolderPath = uigetdir;
                    FolderContent = dir(FolderPath); %#ok<NASGU>
                    uiwait(FolderPath);
                catch
                    app.LoadCreateError.Text = 'Invalid directory, try again';
                end
                app.LoadTDMSButton.Enable = 'on';
            else
                folder = fileparts(which(mfilename));
                addpath(genpath(folder));
                FolderPath = app.Path;
                FolderContent = dir(FolderPath); %#ok<NASGU>
            end

            % Load the appropriate script file to handle loaded TDMS data
            try
                PIASloadTDMSfolder;
                % Check if required addons are installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox')
            catch ERROR
                switch ERROR.identifier
                    case 'MATLAB:UndefinedFunction'
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        return;
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        app.LoadCreateError.Text = "Please install 'Image Processing Toolbox' to continue";
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Unknown error';
                        return;
                end
            end

            % Separate property to distinguish proper path
            app.Path = FolderPath;

            % Set all default variables from the loadTDMSfolder to public and
            % private properties for both applications to call on
            %             app.time = Time;
            app.BackgroundLevel = BackgroundLvl;
            app.rst = 0;
            %             LogTimeArr = LogTimeArray;
            app.KinInd = 1;
            %             IsLogDataOnly=false;
            app.changeAxes = true;
            app.ShadeArea = [];

            %             % Reveal all the hidden objects
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            %             SpecPlot.Visible = true; %RG
            %             PlotDetailsLabel.Visible = true;
            %             LinearDataButton.Visible = true;
            %             LogDataButton.Visible=true;
            %             ExportLogDataCSVButton.Visible = true;
            %             ExportSpectraDataButton.Visible = true;
            %             CentertimesforspectraalongwiththeirwidthsLabel.Visible = true;
            %             KeepSpecPlotYaxisfixedCheckBox.Visible = true;
            %             BackgroundLvlDisplay.Visible = true;
            %             SaveVarstoWorkspaceButton.Visible = true;
            %             LogLogFigureButton.Visible = true;
            %             ExportFiguresButton.Visible = true;
            %             ResetButton.Visible = true;
            %             KeepKinPlotaxisfixedCheckBox.Value = 0;
            %             ButtonGroup.SelectedObject =
            %             LinearYscaleButton;
            %             LogarithmicdatawillbeexportedtoCSVLabel.Visible = false;

            % Check if TAS or TAM and sort columns by wavelength or pos
            app.waves = LinData.Properties.VariableNames(2:end);


            % Look at the data tables and sort by respective header
            waves_order = cellfun(@(x)sscanf(x, 'x%d'), app.waves);
            [~, sortedWavesOrder] = sort(waves_order);
            app.BackgroundLevel = app.BackgroundLevel(:,sortedWavesOrder);

            %Change sorting array to fit Time in first index
            sortedWavesOrder = sortedWavesOrder + 1;
            sortedWavesOrder = [1 sortedWavesOrder];
            app.LogDataSort = LogData(:, sortedWavesOrder);
            app.LinDataSort = LinData(:, sortedWavesOrder);

            % And erase the useless 'x' char in wavelength num
            for i = 1:1:length(app.waves)
                app.waves(i) = erase(app.waves(i), 'x');
            end

            % Populate properties to use throughout whole app
            app.dataRG = app.LinDataSort; % Using lin-lin now for PIAS
            app.timeRG = app.dataRG(:,1);

            % Turn useful data into arrays
            app.TimeLinSpecArray = table2array(app.timeRG);
            app.SortedYLinArray = table2array(app.LinDataSort(:,2:end));

            % Find the number of traces
            app.Traces = size(app.LinDataSort, 2) - 1;

            % Output folder path in text box and continue
            [~, name] = fileparts(app.Path);
            app.LoadCreateError.Text = "Opened: " + name;

            buildSpecData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Create property to know when reset button is pushed
            app.rst = 1;

            % Save path of loaded data so you don't have to call data again
            % Then clear properties and reset to default
            tempPath = app.Path;
            clearProperties(app);
            app.Path = tempPath;

            loadDataButton(app);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd - 1;
            if app.KinInd < 1
                app.KinInd = 1;
            end

            % Check if need to plot Log data or Lin data
            updateData(app);
            plotKinData(app);
            YCheck(app);
            updateAreas(app);
            RGPlot(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd + 1;
            if app.KinInd > app.Traces
                app.KinInd = app.Traces;
            end

            % Check if need to plot loaded Log data or Lin data
            updateData(app);
            plotKinData(app);
            YCheck(app);
            updateAreas(app);
            RGPlot(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addRow(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Store important properties about the change like indices
            newData = event.NewData;
            indices = event.Indices;
            i = indices(1);

            % Only editing time fields column and not width
            if indices(2) == 1
                % Store old and new times field values
                previousTime = app.TimeFields(i);
                newTime = str2double(newData);

                % If the number doesn't change, do nothing
                if previousTime == str2double(newData)
                    return
                elseif newData == ""
                    deleteRow(app, indices);
                    return
                elseif isnan(newTime)
                    app.UITable.Data(indices) = {sprintf('%.2e\n',previousTime)};
                    return
                end

                % Update the array spot with new data in double form
                app.TimeFields(i) = newTime;
                StartTime = newTime*(1 - (app.UITable.Data{i,2})/200);
                EndTime = newTime*(1 + (app.UITable.Data{i,2})/200);

                % Manually update each property with these new positions
                app.TimeWidths(i) = EndTime-StartTime;
                app.ShadeArea(i,:) = [StartTime EndTime];

                % Make sure the newly added/edited timeFields is sorted
                [app.TimeFields,I] = sort(app.TimeFields);
                app.TimeWidths = app.TimeWidths(I);

                Tester(app,1);
                cla(app.KinPlot,"reset");

                drawAreas(app);
                updateData(app);
                plotKinData(app);
                YCheck(app);
                RGPlot(app);

                % Editing time widths
            else
                % Store old width and new width in vars
                previousWidth = app.TimeWidths(i);
                newWidth = newData;

                % If the number doesn't change, do nothing
                if previousWidth == str2double(newData)
                    return
                elseif isnan(newData)
                    deleteRow(app, indices);
                    return
                end

                % Calculate the positions of the new width from the same centre
                newWidth = newWidth/100;
                StartTime = app.TimeFields(i)*(1 - newWidth/2);
                EndTime = app.TimeFields(i) * (1 + newWidth/2);

                % Manually update each property with these new positions
                app.TimeWidths(i) = EndTime-StartTime;
                app.ShadeArea(i,:) = [StartTime EndTime];
                app.XPointsROI{i}.Position(1) = StartTime;
                app.XPointsROI{i}.Position(3) = app.TimeWidths(i);

                Tester(app);
                updateData(app);
                plotKinData(app);
                YCheck(app);
                RGPlot(app);
            end
        end

        % Button pushed function: ExportSpectraDataButton
        function ExportSpectraDataButtonPushed(app, event)
            % Use path from data folder to export spectra data
            [~, name] = fileparts(app.Path);
            OutputName = app.Path + "\" + name + "_SPECTRADATA.txt";
            sorted_wavelengths = reshape(app.Plot_Wavelengths, [],1);
            DataToWrite = [sorted_wavelengths app.SpecDataPlot];

            % Create vars to export data to txt
            Headers = [];
            for i =1:1:size(app.SpecDataPlot,2)
                Headers(1,i+1) = mean(app.ShadeArea(i,:)); %#ok<AGROW>
            end
            OutputData = [Headers; DataToWrite]';
            format_str = ['%.1f' repmat(' %.7f',1,size(app.SpecDataPlot,2)) '\r\n'];
            RGID = fopen(OutputName,'w');
            fprintf(RGID,format_str,OutputData);
            fclose(RGID);
        end

        % Button pushed function: ExportFilteredCSVButton
        function ExportFilteredCSVButtonPushed(app, event)
            % Reveal export message
            app.ExportationLabel.Visible = true;

            % Create file name/path to export to
            [~, name] = fileparts(app.Path);

            loc = app.Path + "\" + name + "_KIN_TAS.csv";

            % Write CSV to table and return a message notifying as such
            %%%%% Don't have a variable for this yet  
%             writetable(app.LogDataSort, loc, "Delimiter", ',', "QuoteStrings",true);
            app.ExportationLabel.Text = strcat('Data has been exported to',loc);
            pause(5);
            app.ExportationLabel.Visible = false;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 222};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadTDMSButton = uibutton(app.GridLayout2, 'push');
            app.LoadTDMSButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTDMSButtonPushed, true);
            app.LoadTDMSButton.Text = 'Load TDMS';
            app.LoadCreateError = uilabel(app.GridLayout2);
            app.LoadCreateError.Text = '';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportationLabel = uilabel(app.GridLayout2);
            app.ExportationLabel.Text = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.KinPlot = uiaxes(app.CenterPanel);
            title(app.KinPlot, {'Kinetics'; ''})
            xlabel(app.KinPlot, 'Time (s)')
            ylabel(app.KinPlot, 'ΔOD or %Abs')
            zlabel(app.KinPlot, 'Z')
            app.NormKinPlot = uiaxes(app.CenterPanel);
            title(app.NormKinPlot, {'Normalized Kinetics'; ''})
            xlabel(app.NormKinPlot, 'Time (s)')
            ylabel(app.NormKinPlot, 'ΔOD or %Abs')
            zlabel(app.NormKinPlot, 'Z')
            app.SpecPlot = uiaxes(app.CenterPanel);
            title(app.SpecPlot, 'Spectra')
            xlabel(app.SpecPlot, 'Wavelength (nm)')
            ylabel(app.SpecPlot, 'ΔOD or %Abs')
            zlabel(app.SpecPlot, 'Z')
            app.PrevButton = uibutton(app.CenterPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.CenterPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.PlotDetailsLabel = uilabel(app.GridLayout4);
            app.PlotDetailsLabel.Text = 'Plot Details';
            app.Description = uilabel(app.GridLayout4);
            app.Description.Text = {'Center times for spectra'; 'along with their widths'};
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Centre Time'; 'Time Width (%)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.BackgroundLvlDisplay = uilabel(app.GridLayout4);
            app.BackgroundLvlDisplay.Text = 'Background level was _ V';
            app.KineticLevelDisplay = uilabel(app.GridLayout4);
            app.KineticLevelDisplay.Text = 'Displaying kinetic at _ nm';
            app.ExportFilteredCSVButton = uibutton(app.GridLayout4, 'push');
            app.ExportFilteredCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFilteredCSVButtonPushed, true);
            app.ExportFilteredCSVButton.Text = 'Export Filtered CSV';
            app.ExportSpectraDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportSpectraDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectraDataButtonPushed, true);
            app.ExportSpectraDataButton.Text = 'Export SpectraData';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIASpectra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end